classdef timezoneSleep < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RightPanel                      matlab.ui.container.Panel
        DaysforAdjustmentLabel          matlab.ui.control.Label
        DaysButton                      matlab.ui.control.Button
        TimezoneSleepAdjusterLabel      matlab.ui.control.Label
        RestartButton                   matlab.ui.control.Button
        DestinationTimeEditField        matlab.ui.control.NumericEditField
        DestinationTimeEditFieldLabel   matlab.ui.control.Label
        OriginTimeEditField             matlab.ui.control.NumericEditField
        OriginTimeEditFieldLabel        matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        FlightDestinationTimezoneGauge  matlab.ui.control.LinearGauge
        FlightOriginTimezoneGauge       matlab.ui.control.LinearGauge
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            timezoneDiff = app.OriginTimeEditField.Value - app.DestinationTimeEditField.Value;
            disp('start');
            disp(timezoneDiff);
            set(app.StartButton, 'Enable', 0);
            
            app.FlightOriginTimezoneGauge.ScaleColorLimits(2) = app.FlightOriginTimezoneGauge.ScaleColorLimits(2) - 1;
            if app.FlightOriginTimezoneGauge.ScaleColorLimits(3) == 1
                app.FlightOriginTimezoneGauge.ScaleColors = [1,1,1; 0, 0, 1];
                app.FlightOriginTimezoneGauge.ScaleColorLimits(4) = app.FlightOriginTimezoneGauge.ScaleColorLimits(4) - 1;
            else
                app.FlightOriginTimezoneGauge.ScaleColorLimits(3) = app.FlightOriginTimezoneGauge.ScaleColorLimits(3) - 1;
            end
            app.FlightOriginTimezoneGauge.Value = app.FlightOriginTimezoneGauge.Value + 1;
            app.FlightDestinationTimezoneGauge.Value = app.FlightDestinationTimezoneGauge.Value + 1;

            while app.StartButton.Enable == 0 
                if app.FlightOriginTimezoneGauge.Value == app.OriginTimeEditField.Value
                    %disp('1');
                    %disp(app.FlightOriginTimezoneGauge.ScaleColorLimits(2));

                    %disp('2');
                    %disp(app.FlightDestinationTimezoneGauge.ScaleColorLimits(2)-11);
                    
                    if app.FlightOriginTimezoneGauge.ScaleColorLimits(2) ~= app.FlightDestinationTimezoneGauge.ScaleColorLimits(2) + timezoneDiff
                        
                        %disp(app.FlightOriginTimezoneGauge.ScaleColorLimits);
                        
                        
                        if app.FlightOriginTimezoneGauge.ScaleColorLimits(3) == 1
                            app.FlightOriginTimezoneGauge.ScaleColors = [1,1,1; 0,0,1];
                            app.FlightOriginTimezoneGauge.ScaleColorLimits(2) = app.FlightOriginTimezoneGauge.ScaleColorLimits(2) - 1;
                            %diff = app.FlightOriginTimezoneGauge.ScaleColorLimits(4) - app.FlightOriginTimezoneGauge.ScaleColorLimits(2);
                            %disp('diff');
                            %disp(diff);
    
                            if app.FlightOriginTimezoneGauge.ScaleColorLimits(4) - app.FlightOriginTimezoneGauge.ScaleColorLimits(2) == 9
                                %disp('should be here');
                                app.FlightOriginTimezoneGauge.ScaleColorLimits(4) = app.FlightOriginTimezoneGauge.ScaleColorLimits(4) - 1;
    
                            end
    
                        else
                            app.FlightOriginTimezoneGauge.ScaleColorLimits(2) = app.FlightOriginTimezoneGauge.ScaleColorLimits(2) - 1;
                            app.FlightOriginTimezoneGauge.ScaleColorLimits(3) = app.FlightOriginTimezoneGauge.ScaleColorLimits(3) - 1;
                        end
                        pause(0.1);
                        app.FlightOriginTimezoneGauge.Value = app.FlightOriginTimezoneGauge.Value + 1;
                        app.FlightDestinationTimezoneGauge.Value = app.FlightDestinationTimezoneGauge.Value + 1;
                    else
                        %disp('done');
                    end
                
    
                else
                    %disp('1');
                    %disp(app.FlightOriginTimezoneGauge.ScaleColorLimits(2));

                    %disp('2');
                    %disp(app.FlightDestinationTimezoneGauge.ScaleColorLimits(2)-11);
                    if app.FlightOriginTimezoneGauge.ScaleColorLimits(2) == app.FlightDestinationTimezoneGauge.ScaleColorLimits(2) + timezoneDiff


                        while app.FlightOriginTimezoneGauge.Value ~= app.FlightOriginTimezoneGauge.ScaleColorLimits(2)
                            app.FlightDestinationTimezoneGauge.Value = app.FlightDestinationTimezoneGauge.Value + 1;
                            app.FlightOriginTimezoneGauge.Value = app.FlightOriginTimezoneGauge.Value + 1;
                            pause(0.5);
                        end
                        
                        app.FlightDestinationTimezoneGauge.ScaleColors = [1, 0, 0; 1, 0, 0];
                        app.FlightOriginTimezoneGauge.ScaleColors = [0, 1, 0; 0, 1, 0];

                        break
                        

                    else
                        
                        
                        if app.FlightOriginTimezoneGauge.Value < 24
                            app.FlightOriginTimezoneGauge.Value = app.FlightOriginTimezoneGauge.Value + 1;
                        
                        else
                            app.FlightOriginTimezoneGauge.Value = 0;
                        end
            
                        if app.FlightDestinationTimezoneGauge.Value < 24
                            app.FlightDestinationTimezoneGauge.Value = app.FlightDestinationTimezoneGauge.Value + 1;
                        else
                            app.FlightDestinationTimezoneGauge.Value = 0;
                        end   

                        pause(0.1);
                    end
                    
                    
                end 
                
            end
                
        app.DaysButton.Text = int2str(timezoneDiff*-1);


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

            set(app.StartButton,'Enable',1);

           
        end

        % Value changed function: OriginTimeEditField
        function OriginTimeEditFieldValueChanged(app, event)
            app.FlightOriginTimezoneGauge.Value = app.OriginTimeEditField.Value;
            
        end

        % Value changed function: DestinationTimeEditField
        function DestinationTimeEditFieldValueChanged(app, event)
            app.FlightDestinationTimezoneGauge.Value = app.DestinationTimeEditField.Value;
            
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            app.OriginTimeEditField.Value = 0;
            app.DestinationTimeEditField.Value = 0;
            app.FlightOriginTimezoneGauge.Value = 0;
            app.FlightDestinationTimezoneGauge.Value = 0;
            app.FlightDestinationTimezoneGauge.ScaleColorLimits = [0, 6; 20, 23];
            app.FlightOriginTimezoneGauge.ScaleColorLimits = [0, 6; 20, 23];
            app.FlightDestinationTimezoneGauge.ScaleColors = [0, 0, 1; 0, 0, 1];
            app.FlightOriginTimezoneGauge.ScaleColors = [0, 0, 1; 0, 0, 1];
            set(app.StartButton, 'Enable', 1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RightPanel = uipanel(app.UIFigure);
            app.FlightOriginTimezoneGauge = uigauge(app.RightPanel, 'linear');
            app.FlightOriginTimezoneGauge.Limits = [0 23];
            app.FlightOriginTimezoneGauge.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23];
            app.FlightOriginTimezoneGauge.MajorTickLabels = {'00', '01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23'};
            app.FlightOriginTimezoneGauge.MinorTicks = [];
            app.FlightDestinationTimezoneGauge = uigauge(app.RightPanel, 'linear');
            app.FlightDestinationTimezoneGauge.Limits = [0 23];
            app.FlightDestinationTimezoneGauge.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23];
            app.FlightDestinationTimezoneGauge.MajorTickLabels = {'00', '01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23'};
            app.FlightDestinationTimezoneGauge.MinorTicks = [];
            app.StartButton = uibutton(app.RightPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.RightPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.OriginTimeEditFieldLabel = uilabel(app.RightPanel);
            app.OriginTimeEditFieldLabel.Text = 'Origin Time';
            app.OriginTimeEditField = uieditfield(app.RightPanel, 'numeric');
            app.OriginTimeEditField.ValueChangedFcn = createCallbackFcn(app, @OriginTimeEditFieldValueChanged, true);
            app.DestinationTimeEditFieldLabel = uilabel(app.RightPanel);
            app.DestinationTimeEditFieldLabel.Text = 'Destination Time';
            app.DestinationTimeEditField = uieditfield(app.RightPanel, 'numeric');
            app.DestinationTimeEditField.ValueChangedFcn = createCallbackFcn(app, @DestinationTimeEditFieldValueChanged, true);
            app.RestartButton = uibutton(app.RightPanel, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.TimezoneSleepAdjusterLabel = uilabel(app.RightPanel);
            app.TimezoneSleepAdjusterLabel.Text = 'Timezone Sleep Adjuster';
            app.DaysButton = uibutton(app.RightPanel, 'push');
            app.DaysButton.Text = '(Days)';
            app.DaysforAdjustmentLabel = uilabel(app.RightPanel);
            app.DaysforAdjustmentLabel.Text = 'Days for Adjustment:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timezoneSleep

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end